classdef editProgtable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dataEditor     matlab.ui.Figure
        dataEditTable  matlab.ui.control.Table
        ch_string      matlab.ui.control.DropDown
        b_save         matlab.ui.control.Button
    end

    properties (Access = public)
        day_assessment_pub
        night_assessment_pub
        assessment_fullday_pub
        terr_assess_pub
        calc_tables
        
        %%%%
        stations_pub
        phen_pub
    end
    
    properties (Access = private)
        mainApp
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainWin)
            app.mainApp = mainWin;
            
            load('night_assessment.mat');
            load('day_assessment.mat');
            load('assessment_fullday.mat');
            load('calc_table.mat');
            load('calc_table_p.mat');
            load('calc_table_phen.mat');
            
            app.day_assessment_pub = day_assessment;
            app.night_assessment_pub = night_assessment;
            app.assessment_fullday_pub = assessment_fullday;
            app.calc_tables.calcTable = calc_table;
            app.calc_tables.calcTable_p = calc_table_p;
            app.calc_tables.calcTable_phen = calc_table_phen;
            
            app.stations_pub = app.day_assessment_pub(4:end,2);
            app.phen_pub = app.day_assessment_pub(3,10:20);
        end

        % Value changed function: ch_string
        function ch_stringValueChanged(app, event)
            if str2double(app.ch_string.Value) == 2
                
                app.dataEditor.Position = [250,250,391,625];
                app.dataEditTable.Position = [12,20,234,587];
                app.ch_string.Position = [282,573,75,22];
                app.b_save.Position = [278,462,85,36];
                app.dataEditTable.Data = app.stations_pub;
                app.b_save.Enable = 1;
            elseif str2double(app.ch_string.Value) == 3
                
                app.dataEditor.Position = [250,250,1022,177];
                app.dataEditTable.Position = [9,16,904,152];
                app.ch_string.Position = [925,102,75,22];
                app.b_save.Position = [920,16,85,36];
                app.dataEditTable.Data = app.phen_pub;
                app.b_save.Enable = 1;
            else
                app.dataEditor.Position = [250,250,264,177];
                app.dataEditTable.Position = [17,28,132,123];
                app.ch_string.Position = [171,129,75,22];
                app.b_save.Position = [166,28,85,36];
                app.dataEditTable.ColumnName = {};
                app.dataEditTable.Data = {};
                app.b_save.Enable = 0;
            end
        end

        % Cell edit callback: dataEditTable
        function dataEditTableCellEdit(app, event)
            if str2double(app.ch_string.Value) == 2
                app.stations_pub = app.dataEditTable.Data;
            else
                app.phen_pub = app.dataEditTable.Data;
            end
        end

        % Button pushed function: b_save
        function b_saveButtonPushed(app, event)
            % ==== stations_saver ==========
            app.day_assessment_pub(4:end,2) = app.stations_pub;
            app.night_assessment_pub(4:end,2) = app.stations_pub;
            app.assessment_fullday_pub(3:end,2) = app.stations_pub;
            app.calc_tables.calcTable(3:end-7,2) = app.stations_pub;
            app.calc_tables.calcTable_p(3:end-13,2) = app.stations_pub;
            app.calc_tables.calcTable_phen(3:end-13,2) = app.stations_pub;
            % ======= phenomenons_saver ====
            
            app.day_assessment_pub(3,10:20) = app.phen_pub;
            app.day_assessment_pub(3,22:32) = app.phen_pub;
            app.night_assessment_pub(3,10:20) = app.phen_pub;
            app.night_assessment_pub(3,22:32) = app.phen_pub;
            app.calc_tables.calcTable_phen(2,4:14) = app.phen_pub;
            app.calc_tables.calcTable_phen(2,16:26) = app.phen_pub;
            app.calc_tables.calcTable_phen(2,29:39) = app.phen_pub;
            app.calc_tables.calcTable_phen(2,41:51) = app.phen_pub;
            
            day_assessment = app.day_assessment_pub;
            night_assessment = app.night_assessment_pub;
            assessment_fullday = app.assessment_fullday_pub;
            calc_table = app.calc_tables.calcTable;
            calc_table_p = app.calc_tables.calcTable_p;
            calc_table_phen = app.calc_tables.calcTable_phen;
            
            save('night_assessment.mat', 'night_assessment');
            save('day_assessment.mat', 'day_assessment');
            save('assessment_fullday.mat', 'assessment_fullday');
            save('calc_table.mat', 'calc_table');
            save('calc_table_p.mat', 'calc_table_p');
            save('calc_table_phen.mat', 'calc_table_phen');
            
            uialert(app.mainApp.Easy_Assessment, 'Изменения сохранены. После перезапуска программы таблица по умолчанию будет изменена', 'Внимание', 'Icon', 'info');
            delete(app.dataEditor);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.dataEditor = uifigure;
            app.dataEditor.Name = 'Редактор таблицы программы';
            app.dataEditTable = uitable(app.dataEditor);
            app.dataEditTable.ColumnName = '';
            app.dataEditTable.RowName = {};
            app.dataEditTable.ColumnEditable = true;
            app.dataEditTable.CellEditCallback = createCallbackFcn(app, @dataEditTableCellEdit, true);
            app.ch_string = uidropdown(app.dataEditor);
            app.ch_string.Items = {'*---------*', 'Станции', 'Явления'};
            app.ch_string.ItemsData = {'1', '2', '3'};
            app.ch_string.ValueChangedFcn = createCallbackFcn(app, @ch_stringValueChanged, true);
            app.ch_string.Value = '1';
            app.b_save = uibutton(app.dataEditor, 'push');
            app.b_save.ButtonPushedFcn = createCallbackFcn(app, @b_saveButtonPushed, true);
            app.b_save.Enable = 'off';
            app.b_save.Text = {'Сохранить'; 'изменения'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = editProgtable(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.dataEditor)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dataEditor)
        end
    end
end